classdef prototipe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        ConstantespticasTab  matlab.ui.container.Tab
        dropDownConst        matlab.ui.control.DropDown
        figuraConstantes     matlab.ui.control.UIAxes
        EsferasTab           matlab.ui.container.Tab
        nmSlider             matlab.ui.control.Slider
        nmSliderLabel        matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        cb100                matlab.ui.control.CheckBox
        cb90                 matlab.ui.control.CheckBox
        cb80                 matlab.ui.control.CheckBox
        cb70                 matlab.ui.control.CheckBox
        cb60                 matlab.ui.control.CheckBox
        cb50                 matlab.ui.control.CheckBox
        cb40                 matlab.ui.control.CheckBox
        cb30                 matlab.ui.control.CheckBox
        cb20                 matlab.ui.control.CheckBox
        cb10                 matlab.ui.control.CheckBox
        dropDownSecond       matlab.ui.control.DropDown
        dropDownFirst        matlab.ui.control.DropDown
        imagenCampo          matlab.ui.control.Image
        campoLabel           matlab.ui.control.Label
        figuraPropiedades    matlab.ui.control.UIAxes
        BarrasTab            matlab.ui.container.Tab
    end

    properties (Access = private)
        waveOro10  = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 1); qAbs10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 2); qSca10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 3); qExt10 = importdata('data/Oro/comsol_oro_10_nm.txt', ',', 1).data(:, 4);
        waveOro20  = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 1); qAbs20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 2); qSca20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 3); qExt20 = importdata('data/Oro/comsol_oro_20_nm.txt', ',', 1).data(:, 4);
        waveOro30  = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 1); qAbs30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 2); qSca30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 3); qExt30 = importdata('data/Oro/comsol_oro_30_nm.txt', ',', 1).data(:, 4);
        waveOro40  = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 1); qAbs40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 2); qSca40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 3); qExt40 = importdata('data/Oro/comsol_oro_40_nm.txt', ',', 1).data(:, 4);
        waveOro50  = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 1); qAbs50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 2); qSca50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 3); qExt50 = importdata('data/Oro/comsol_oro_50_nm.txt', ',', 1).data(:, 4);
        waveOro60  = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 1); qAbs60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 2); qSca60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 3); qExt60 = importdata('data/Oro/comsol_oro_60_nm.txt', ',', 1).data(:, 4);
        waveOro70  = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 1); qAbs70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 2); qSca70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 3); qExt70 = importdata('data/Oro/comsol_oro_70_nm.txt', ',', 1).data(:, 4);
        waveOro80  = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 1); qAbs80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 2); qSca80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 3); qExt80 = importdata('data/Oro/comsol_oro_80_nm.txt', ',', 1).data(:, 4);
        waveOro90  = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 1); qAbs90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 2); qSca90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 3); qExt90 = importdata('data/Oro/comsol_oro_90_nm.txt', ',', 1).data(:, 4);
        waveOro100  = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 1); qAbs100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 2); qSca100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 3); qExt100 = importdata('data/Oro/comsol_oro_100_nm.txt', ',', 1).data(:, 4);
        OA10; OA20; OA30; OA40; OA50; OA60; OA70; OA80; OA90; OA100;
        OS10; OS20; OS30; OS40; OS50; OS60; OS70; OS80; OS90; OS100;
        OE10; OE20; OE30; OE40; OE50; OE60; OE70; OE80; OE90; OE100;
        wavePlata10  = importdata('data/Plata/comsol_plata_10_nm.txt', ',', 1).data(:, 1); qAbsP10 = importdata('data/Plata/comsol_plata_10_nm.txt', ',', 1).data(:, 2); qScaP10 = importdata('data/Plata/comsol_plata_10_nm.txt', ',', 1).data(:, 3); qExtP10 = importdata('data/Plata/comsol_plata_10_nm.txt', ',', 1).data(:, 4);
        wavePlata20  = importdata('data/Plata/comsol_plata_20_nm.txt', ',', 1).data(:, 1); qAbsP20 = importdata('data/Plata/comsol_plata_20_nm.txt', ',', 1).data(:, 2); qScaP20 = importdata('data/Plata/comsol_plata_20_nm.txt', ',', 1).data(:, 3); qExtP20 = importdata('data/Plata/comsol_plata_20_nm.txt', ',', 1).data(:, 4);
        wavePlata30  = importdata('data/Plata/comsol_plata_30_nm.txt', ',', 1).data(:, 1); qAbsP30 = importdata('data/Plata/comsol_plata_30_nm.txt', ',', 1).data(:, 2); qScaP30 = importdata('data/Plata/comsol_plata_30_nm.txt', ',', 1).data(:, 3); qExtP30 = importdata('data/Plata/comsol_plata_30_nm.txt', ',', 1).data(:, 4);
        wavePlata40  = importdata('data/Plata/comsol_plata_40_nm.txt', ',', 1).data(:, 1); qAbsP40 = importdata('data/Plata/comsol_plata_40_nm.txt', ',', 1).data(:, 2); qScaP40 = importdata('data/Plata/comsol_plata_40_nm.txt', ',', 1).data(:, 3); qExtP40 = importdata('data/Plata/comsol_plata_40_nm.txt', ',', 1).data(:, 4);
        wavePlata50  = importdata('data/Plata/comsol_plata_50_nm.txt', ',', 1).data(:, 1); qAbsP50 = importdata('data/Plata/comsol_plata_50_nm.txt', ',', 1).data(:, 2); qScaP50 = importdata('data/Plata/comsol_plata_50_nm.txt', ',', 1).data(:, 3); qExtP50 = importdata('data/Plata/comsol_plata_50_nm.txt', ',', 1).data(:, 4);
        wavePlata60  = importdata('data/Plata/comsol_plata_60_nm.txt', ',', 1).data(:, 1); qAbsP60 = importdata('data/Plata/comsol_plata_60_nm.txt', ',', 1).data(:, 2); qScaP60 = importdata('data/Plata/comsol_plata_60_nm.txt', ',', 1).data(:, 3); qExtP60 = importdata('data/Plata/comsol_plata_60_nm.txt', ',', 1).data(:, 4);
        wavePlata70  = importdata('data/Plata/comsol_plata_70_nm.txt', ',', 1).data(:, 1); qAbsP70 = importdata('data/Plata/comsol_plata_70_nm.txt', ',', 1).data(:, 2); qScaP70 = importdata('data/Plata/comsol_plata_70_nm.txt', ',', 1).data(:, 3); qExtP70 = importdata('data/Plata/comsol_plata_70_nm.txt', ',', 1).data(:, 4);
        wavePlata80  = importdata('data/Plata/comsol_plata_80_nm.txt', ',', 1).data(:, 1); qAbsP80 = importdata('data/Plata/comsol_plata_80_nm.txt', ',', 1).data(:, 2); qScaP80 = importdata('data/Plata/comsol_plata_80_nm.txt', ',', 1).data(:, 3); qExtP80 = importdata('data/Plata/comsol_plata_80_nm.txt', ',', 1).data(:, 4);
        wavePlata90  = importdata('data/Plata/comsol_plata_90_nm.txt', ',', 1).data(:, 1); qAbsP90 = importdata('data/Plata/comsol_plata_90_nm.txt', ',', 1).data(:, 2); qScaP90 = importdata('data/Plata/comsol_plata_90_nm.txt', ',', 1).data(:, 3); qExtP90 = importdata('data/Plata/comsol_plata_90_nm.txt', ',', 1).data(:, 4);
        wavePlata100  = importdata('data/Plata/comsol_plata_100_nm.txt', ',', 1).data(:, 1); qAbsP100 = importdata('data/Plata/comsol_plata_100_nm.txt', ',', 1).data(:, 2); qScaP100 = importdata('data/Plata/comsol_plata_100_nm.txt', ',', 1).data(:, 3); qExtP100 = importdata('data/Plata/comsol_plata_100_nm.txt', ',', 1).data(:, 4);
        PA10; PA20; PA30; PA40; PA50; PA60; PA70; PA80; PA90; PA100;
        PS10; PS20; PS30; PS40; PS50; PS60; PS70; PS80; PS90; PS100;
        PE10; PE20; PE30; PE40; PE50; PE60; PE70; PE80; PE90; PE100;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: dropDownFirst
        function dropDownFirstValueChanged(app, event)
            value = app.dropDownFirst.Value;
            cla(app.figuraPropiedades);
            app.figuraPropiedades.Title.String = "";
            xlabel(app.figuraPropiedades, "");
            ylabel(app.figuraPropiedades, "");
            legend(app.figuraPropiedades, "off");
            app.dropDownSecond.Value = "";
            app.cb10.Enable = "off"; app.cb10.Value = 0;
            app.cb20.Enable = "off"; app.cb20.Value = 0;
            app.cb30.Enable = "off"; app.cb30.Value = 0;
            app.cb40.Enable = "off"; app.cb40.Value = 0;
            app.cb50.Enable = "off"; app.cb50.Value = 0;
            app.cb60.Enable = "off"; app.cb60.Value = 0;
            app.cb70.Enable = "off"; app.cb70.Value = 0;
            app.cb80.Enable = "off"; app.cb80.Value = 0;
            app.cb90.Enable = "off"; app.cb90.Value = 0;
            app.cb100.Enable = "off"; app.cb100.Value = 0;
            app.campoLabel.Visible = "on";
            if value ~= ""
                app.dropDownSecond.Enable = "on";
                app.nmSlider.Visible = "on";
                if value == "Oro"
                    app.imagenCampo.Visible = "on";
                    app.imagenCampo.ImageSource = 'data/CampoCercanoOro.png';
                end
                if value == "Plata"
                    app.imagenCampo.Visible = "on";
                    app.imagenCampo.ImageSource = 'data/CampoCercanoPlata.png';
                end
            else
                app.dropDownSecond.Enable = "off";
                app.nmSlider.Visible = "off";
                app.campoLabel.Visible = "off";
                app.imagenCampo.Visible = "off";
            end
        end

        % Value changed function: dropDownSecond
        function dropDownSecondValueChanged(app, event)
            value = app.dropDownSecond.Value;
            xlabel(app.figuraPropiedades, "Longitud de onda $({\lambda}) \ [nm]$", "Interpreter", "latex");
            ylabel(app.figuraPropiedades, "Secci\'on Eficaz ($m^2$)", "Interpreter", "latex");
            xlim(app.figuraPropiedades, [400, 800]);
            yticks(app.figuraPropiedades, []);
            if value ~= ""
                app.cb10.Enable = "on"; app.cb10.Value = 1;
                app.cb20.Enable = "on"; app.cb20.Value = 1;
                app.cb30.Enable = "on"; app.cb30.Value = 1;
                app.cb40.Enable = "on"; app.cb40.Value = 1;
                app.cb50.Enable = "on"; app.cb50.Value = 1;
                app.cb60.Enable = "on"; app.cb60.Value = 1;
                app.cb70.Enable = "on"; app.cb70.Value = 1;
                app.cb80.Enable = "on"; app.cb80.Value = 1;
                app.cb90.Enable = "on"; app.cb90.Value = 1;
                app.cb100.Enable = "on"; app.cb100.Value = 1;
                if app.dropDownFirst.Value == "Oro"
                    if value == "Absorción simulada"
                        title(app.figuraPropiedades, "Absorci\'on simulada - Oro", "Interpreter", "latex");
                        % yticks(app.figuraPropiedades, []);
                        app.OA10 = plot(app.figuraPropiedades, app.waveOro10, app.qAbs10);
                        hold(app.figuraPropiedades, "on");
                        app.OA20 = plot(app.figuraPropiedades, app.waveOro20, app.qAbs20);
                        hold(app.figuraPropiedades, "on");
                        app.OA30 = plot(app.figuraPropiedades, app.waveOro30, app.qAbs30);
                        hold(app.figuraPropiedades, "on");
                        app.OA40 = plot(app.figuraPropiedades, app.waveOro40, app.qAbs40);
                        hold(app.figuraPropiedades, "on");
                        app.OA50 = plot(app.figuraPropiedades, app.waveOro50, app.qAbs50);
                        hold(app.figuraPropiedades, "on");
                        app.OA60 = plot(app.figuraPropiedades, app.waveOro60, app.qAbs60);
                        hold(app.figuraPropiedades, "on");
                        app.OA70 = plot(app.figuraPropiedades, app.waveOro70, app.qAbs70);
                        hold(app.figuraPropiedades, "on");
                        app.OA80 = plot(app.figuraPropiedades, app.waveOro80, app.qAbs80);
                        hold(app.figuraPropiedades, "on");
                        app.OA90 = plot(app.figuraPropiedades, app.waveOro90, app.qAbs90);
                        hold(app.figuraPropiedades, "on");
                        app.OA100 = plot(app.figuraPropiedades, app.waveOro100, app.qAbs100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                    if value == "Dispersión simulada"
                        title(app.figuraPropiedades, "Dispersi\'on simulada - Oro", "Interpreter", "latex");
                        app.OS10 = plot(app.figuraPropiedades, app.waveOro10, app.qSca10);
                        hold(app.figuraPropiedades, "on");
                        app.OS20 = plot(app.figuraPropiedades, app.waveOro20, app.qSca20);
                        hold(app.figuraPropiedades, "on");
                        app.OS30 = plot(app.figuraPropiedades, app.waveOro30, app.qSca30);
                        hold(app.figuraPropiedades, "on");
                        app.OS40 = plot(app.figuraPropiedades, app.waveOro40, app.qSca40);
                        hold(app.figuraPropiedades, "on");
                        app.OS50 = plot(app.figuraPropiedades, app.waveOro50, app.qSca50);
                        hold(app.figuraPropiedades, "on");
                        app.OS60 = plot(app.figuraPropiedades, app.waveOro60, app.qSca60);
                        hold(app.figuraPropiedades, "on");
                        app.OS70 = plot(app.figuraPropiedades, app.waveOro70, app.qSca70);
                        hold(app.figuraPropiedades, "on");
                        app.OS80 = plot(app.figuraPropiedades, app.waveOro80, app.qSca80);
                        hold(app.figuraPropiedades, "on");
                        app.OS90 = plot(app.figuraPropiedades, app.waveOro90, app.qSca90);
                        hold(app.figuraPropiedades, "on");
                        app.OS100 = plot(app.figuraPropiedades, app.waveOro100, app.qSca100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                    if value == "Extinción simulada"
                        title(app.figuraPropiedades, "Extinci\'on simulada - Oro", "Interpreter", "latex");
                        app.OE10 = plot(app.figuraPropiedades, app.waveOro10, app.qExt10);
                        hold(app.figuraPropiedades, "on");
                        app.OE20 = plot(app.figuraPropiedades, app.waveOro20, app.qExt20);
                        hold(app.figuraPropiedades, "on");
                        app.OE30 = plot(app.figuraPropiedades, app.waveOro30, app.qExt30);
                        hold(app.figuraPropiedades, "on");
                        app.OE40 = plot(app.figuraPropiedades, app.waveOro40, app.qExt40);
                        hold(app.figuraPropiedades, "on");
                        app.OE50 = plot(app.figuraPropiedades, app.waveOro50, app.qExt50);
                        hold(app.figuraPropiedades, "on");
                        app.OE60 = plot(app.figuraPropiedades, app.waveOro60, app.qExt60);
                        hold(app.figuraPropiedades, "on");
                        app.OE70 = plot(app.figuraPropiedades, app.waveOro70, app.qExt70);
                        hold(app.figuraPropiedades, "on");
                        app.OE80 = plot(app.figuraPropiedades, app.waveOro80, app.qExt80);
                        hold(app.figuraPropiedades, "on");
                        app.OE90 = plot(app.figuraPropiedades, app.waveOro90, app.qExt90);
                        hold(app.figuraPropiedades, "on");
                        app.OE100 = plot(app.figuraPropiedades, app.waveOro100, app.qExt100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if value == "Absorción simulada"
                        title(app.figuraPropiedades, "Absorci\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figuraPropiedades, [300, 800]);
                        app.PA10 = plot(app.figuraPropiedades, app.wavePlata10, app.qAbsP10);
                        hold(app.figuraPropiedades, "on");
                        app.PA20 = plot(app.figuraPropiedades, app.wavePlata20, app.qAbsP20);
                        hold(app.figuraPropiedades, "on");
                        app.PA30 = plot(app.figuraPropiedades, app.wavePlata30, app.qAbsP30);
                        hold(app.figuraPropiedades, "on");
                        app.PA40 = plot(app.figuraPropiedades, app.wavePlata40, app.qAbsP40);
                        hold(app.figuraPropiedades, "on");
                        app.PA50 = plot(app.figuraPropiedades, app.wavePlata50, app.qAbsP50);
                        hold(app.figuraPropiedades, "on");
                        app.PA60 = plot(app.figuraPropiedades, app.wavePlata60, app.qAbsP60);
                        hold(app.figuraPropiedades, "on");
                        app.PA70 = plot(app.figuraPropiedades, app.wavePlata70, app.qAbsP70);
                        hold(app.figuraPropiedades, "on");
                        app.PA80 = plot(app.figuraPropiedades, app.wavePlata80, app.qAbsP80);
                        hold(app.figuraPropiedades, "on");
                        app.PA90 = plot(app.figuraPropiedades, app.wavePlata90, app.qAbsP90);
                        hold(app.figuraPropiedades, "on");
                        app.PA100 = plot(app.figuraPropiedades, app.wavePlata100, app.qAbsP100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                    if value == "Dispersión simulada"
                        title(app.figuraPropiedades, "Dispersi\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figuraPropiedades, [300, 800]);
                        app.PS10 = plot(app.figuraPropiedades, app.wavePlata10, app.qScaP10);
                        hold(app.figuraPropiedades, "on");
                        app.PS20 = plot(app.figuraPropiedades, app.wavePlata20, app.qScaP20);
                        hold(app.figuraPropiedades, "on");
                        app.PS30 = plot(app.figuraPropiedades, app.wavePlata30, app.qScaP30);
                        hold(app.figuraPropiedades, "on");
                        app.PS40 = plot(app.figuraPropiedades, app.wavePlata40, app.qScaP40);
                        hold(app.figuraPropiedades, "on");
                        app.PS50 = plot(app.figuraPropiedades, app.wavePlata50, app.qScaP50);
                        hold(app.figuraPropiedades, "on");
                        app.PS60 = plot(app.figuraPropiedades, app.wavePlata60, app.qScaP60);
                        hold(app.figuraPropiedades, "on");
                        app.PS70 = plot(app.figuraPropiedades, app.wavePlata70, app.qScaP70);
                        hold(app.figuraPropiedades, "on");
                        app.PS80 = plot(app.figuraPropiedades, app.wavePlata80, app.qScaP80);
                        hold(app.figuraPropiedades, "on");
                        app.PS90 = plot(app.figuraPropiedades, app.wavePlata90, app.qScaP90);
                        hold(app.figuraPropiedades, "on");
                        app.PS100 = plot(app.figuraPropiedades, app.wavePlata100, app.qScaP100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                    if value == "Extinción simulada"
                        title(app.figuraPropiedades, "Extinci\'on simulada - Plata", "Interpreter", "latex");
                        xlim(app.figuraPropiedades, [300, 800]);
                        app.PE10 = plot(app.figuraPropiedades, app.wavePlata10, app.qExtP10);
                        hold(app.figuraPropiedades, "on");
                        app.PE20 = plot(app.figuraPropiedades, app.wavePlata20, app.qExtP20);
                        hold(app.figuraPropiedades, "on");
                        app.PE30 = plot(app.figuraPropiedades, app.wavePlata30, app.qExtP30);
                        hold(app.figuraPropiedades, "on");
                        app.PE40 = plot(app.figuraPropiedades, app.wavePlata40, app.qExtP40);
                        hold(app.figuraPropiedades, "on");
                        app.PE50 = plot(app.figuraPropiedades, app.wavePlata50, app.qExtP50);
                        hold(app.figuraPropiedades, "on");
                        app.PE60 = plot(app.figuraPropiedades, app.wavePlata60, app.qExtP60);
                        hold(app.figuraPropiedades, "on");
                        app.PE70 = plot(app.figuraPropiedades, app.wavePlata70, app.qExtP70);
                        hold(app.figuraPropiedades, "on");
                        app.PE80 = plot(app.figuraPropiedades, app.wavePlata80, app.qExtP80);
                        hold(app.figuraPropiedades, "on");
                        app.PE90 = plot(app.figuraPropiedades, app.wavePlata90, app.qExtP90);
                        hold(app.figuraPropiedades, "on");
                        app.PE100 = plot(app.figuraPropiedades, app.wavePlata100, app.qExtP100);
                        legend(app.figuraPropiedades, ["10 [nm]", "20 [nm]", "30 [nm]", "40 [nm]", "50 [nm]", "60 [nm]", "70 [nm]", "80 [nm]", "90 [nm]", "100 [nm]"], "Interpreter", "latex");
                        hold(app.figuraPropiedades, "off");
                    end
                end
            else
                cla(app.figuraPropiedades);
                app.figuraPropiedades.Title.String = "";
                xlabel(app.figuraPropiedades, "");
                ylabel(app.figuraPropiedades, "");
                legend(app.figuraPropiedades, "off");
                app.cb10.Enable = "off"; app.cb10.Value = 0;
                app.cb20.Enable = "off"; app.cb20.Value = 0;
                app.cb30.Enable = "off"; app.cb30.Value = 0;
                app.cb40.Enable = "off"; app.cb40.Value = 0;
                app.cb50.Enable = "off"; app.cb50.Value = 0;
                app.cb60.Enable = "off"; app.cb60.Value = 0;
                app.cb70.Enable = "off"; app.cb70.Value = 0;
                app.cb80.Enable = "off"; app.cb80.Value = 0;
                app.cb90.Enable = "off"; app.cb90.Value = 0;
                app.cb100.Enable = "off"; app.cb100.Value = 0;
            end
        end

        % Value changed function: dropDownConst
        function dropDownConstValueChanged(app, event)
            value = app.dropDownConst.Value;
            xlabel(app.figuraConstantes, "Longitud de onda $({\lambda}) \ [nm]$", "Interpreter", "latex");
            ylabel(app.figuraConstantes, "Valor de la constante", "Interpreter", "latex");
            xlim(app.figuraConstantes, [200, 1000]);
            filenameOroConstantes = 'data/index/indexoro.txt';
            filenamePlataConstantes = 'data/index/indexplata.txt';
            delimiterIn = ' ';
            headerlinesIn = 1;
            oroConstantes = importdata(filenameOroConstantes, delimiterIn, headerlinesIn);
            plataConstantes = importdata(filenamePlataConstantes, delimiterIn, headerlinesIn);
            waveOroConstantes = oroConstantes.data(:, 1);
            wavePlataConstantes = plataConstantes.data(:, 1);
            nO = oroConstantes.data(:, 2);
            nP = plataConstantes.data(:, 2);
            kO = oroConstantes.data(:, 3);
            kP = plataConstantes.data(:, 3);
            if value == ""
               cla(app.figuraConstantes);
               app.figuraConstantes.Title.String = "";
               xlabel(app.figuraConstantes, "");
               ylabel(app.figuraConstantes, "");
               legend(app.figuraConstantes, "off");
            end
            if value == "Oro"
               title(app.figuraConstantes, value, "Interpreter", "latex");
               plot(app.figuraConstantes, waveOroConstantes*1000, nO);
               hold(app.figuraConstantes, "on");
               plot(app.figuraConstantes, waveOroConstantes*1000, kO);
               legend(app.figuraConstantes, ["\'Indice de Refracci\'on (n)", "Coeficiente de extinci\'on (k)"], "Interpreter", "latex");
               hold(app.figuraConstantes, "off");
            end
            if value == "Plata"
               title(app.figuraConstantes, value, "Interpreter", "latex");
               plot(app.figuraConstantes, wavePlataConstantes*1000, nP);
               hold(app.figuraConstantes, "on");
               plot(app.figuraConstantes, wavePlataConstantes*1000, kP);
               legend(app.figuraConstantes, ["\'Indice de Refracci\'on (n)", "Coeficiente de extinci\'on (k)"], "Interpreter", "latex");
               hold(app.figuraConstantes, "off");
            end
        end

        % Value changed function: cb10
        function cb10ValueChanged(app, event)
            value = app.cb10.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE10.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS10.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE10.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE10.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS10.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE10.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb20
        function cb20ValueChanged(app, event)
            value = app.cb20.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE20.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS20.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE20.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE20.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS20.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE20.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb30
        function cb30ValueChanged(app, event)
            value = app.cb30.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE30.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS30.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE30.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE30.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS30.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE30.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb40
        function cb40ValueChanged(app, event)
            value = app.cb40.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE40.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS40.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE40.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE40.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS40.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE40.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb50
        function cb50ValueChanged(app, event)
            value = app.cb50.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE50.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS50.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE50.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE50.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS50.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE50.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb60
        function cb60ValueChanged(app, event)
            value = app.cb60.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE60.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS60.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE60.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE60.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS60.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE60.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb70
        function cb70ValueChanged(app, event)
            value = app.cb70.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE70.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS70.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE70.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE70.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS70.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE70.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb80
        function cb80ValueChanged(app, event)
            value = app.cb80.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE80.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS80.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE80.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE80.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS80.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE80.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb90
        function cb90ValueChanged(app, event)
            value = app.cb90.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE90.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS90.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE90.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE90.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS90.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE90.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: cb100
        function cb100ValueChanged(app, event)
            value = app.cb100.Value;
            if value == 0
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE100.Visible = "off";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS100.Visible = "off";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE100.Visible = "off";
                    end
                end
            end
            if value == 1
                if app.dropDownFirst.Value == "Oro"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.OA100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.OS100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.OE100.Visible = "on";
                    end
                end
                if app.dropDownFirst.Value == "Plata"
                    if app.dropDownSecond.Value == "Absorción simulada"
                        app.PA100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Dispersión simulada"
                        app.PS100.Visible = "on";
                    end
                    if app.dropDownSecond.Value == "Extinción simulada"
                        app.PE100.Visible = "on";
                    end
                end
            end
        end

        % Value changed function: nmSlider
        function nmSliderValueChanged(app, event)
            value = app.nmSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            value = event.Source.MajorTicks(minIdx);
            if app.dropDownFirst.Value == "Oro"
                if value == 10
                    app.imagenCampo.ImageSource = 'data/one.jpg';
                end
                if value == 20
                    app.imagenCampo.ImageSource = 'data/two.jpg';
                end
                if value == 30
                    app.imagenCampo.ImageSource = 'data/three.jpg';
                end
                if value == 40
                    app.imagenCampo.ImageSource = 'data/four.jpg';
                end
                if value == 50
                    app.imagenCampo.ImageSource = 'data/five.jpg';
                end
                if value == 60
                    app.imagenCampo.ImageSource = 'data/six.jpg';
                end
                if value == 70
                    app.imagenCampo.ImageSource = 'data/seven.jpg';
                end
                if value == 80
                    app.imagenCampo.ImageSource = 'data/eight.jpg';
                end
                if value == 90
                    app.imagenCampo.ImageSource = 'data/nine.jpg';
                end
                if value == 100
                    app.imagenCampo.ImageSource = 'data/ten.jpg';
                end
            end
            if app.dropDownFirst.Value == "Plata"
                if value == 10
                    app.imagenCampo.ImageSource = 'data/one.jpg';
                end
                if value == 20
                    app.imagenCampo.ImageSource = 'data/two.jpg';
                end
                if value == 30
                    app.imagenCampo.ImageSource = 'data/three.jpg';
                end
                if value == 40
                    app.imagenCampo.ImageSource = 'data/four.jpg';
                end
                if value == 50
                    app.imagenCampo.ImageSource = 'data/five.jpg';
                end
                if value == 60
                    app.imagenCampo.ImageSource = 'data/six.jpg';
                end
                if value == 70
                    app.imagenCampo.ImageSource = 'data/seven.jpg';
                end
                if value == 80
                    app.imagenCampo.ImageSource = 'data/eight.jpg';
                end
                if value == 90
                    app.imagenCampo.ImageSource = 'data/nine.jpg';
                end
                if value == 100
                    app.imagenCampo.ImageSource = 'data/ten.jpg';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConstantespticasTab = uitab(app.TabGroup);
            app.ConstantespticasTab.Title = 'Constantes ópticas';
            app.figuraConstantes = uiaxes(app.ConstantespticasTab);
            zlabel(app.figuraConstantes, 'Z')
            app.dropDownConst = uidropdown(app.ConstantespticasTab);
            app.dropDownConst.Items = {'', 'Oro', 'Plata'};
            app.dropDownConst.ValueChangedFcn = createCallbackFcn(app, @dropDownConstValueChanged, true);
            app.dropDownConst.Value = '';
            app.EsferasTab = uitab(app.TabGroup);
            app.EsferasTab.Title = 'Esferas';
            app.figuraPropiedades = uiaxes(app.EsferasTab);
            zlabel(app.figuraPropiedades, 'Z')
            app.campoLabel = uilabel(app.EsferasTab);
            app.campoLabel.Visible = 'off';
            app.campoLabel.Text = 'Campo eléctrico cercano';
            app.imagenCampo = uiimage(app.EsferasTab);
            app.imagenCampo.Visible = 'off';
            app.Panel = uipanel(app.EsferasTab);
            app.dropDownFirst = uidropdown(app.Panel);
            app.dropDownFirst.Items = {'', 'Oro', 'Plata'};
            app.dropDownFirst.ValueChangedFcn = createCallbackFcn(app, @dropDownFirstValueChanged, true);
            app.dropDownFirst.Placeholder = ' ';
            app.dropDownFirst.Value = '';
            app.dropDownSecond = uidropdown(app.Panel);
            app.dropDownSecond.Items = {'', 'Absorción simulada', 'Dispersión simulada', 'Extinción simulada'};
            app.dropDownSecond.ValueChangedFcn = createCallbackFcn(app, @dropDownSecondValueChanged, true);
            app.dropDownSecond.Enable = 'off';
            app.dropDownSecond.Value = '';
            app.cb10 = uicheckbox(app.Panel);
            app.cb10.ValueChangedFcn = createCallbackFcn(app, @cb10ValueChanged, true);
            app.cb10.Enable = 'off';
            app.cb10.Text = '10';
            app.cb20 = uicheckbox(app.Panel);
            app.cb20.ValueChangedFcn = createCallbackFcn(app, @cb20ValueChanged, true);
            app.cb20.Enable = 'off';
            app.cb20.Text = '20';
            app.cb30 = uicheckbox(app.Panel);
            app.cb30.ValueChangedFcn = createCallbackFcn(app, @cb30ValueChanged, true);
            app.cb30.Enable = 'off';
            app.cb30.Text = '30';
            app.cb40 = uicheckbox(app.Panel);
            app.cb40.ValueChangedFcn = createCallbackFcn(app, @cb40ValueChanged, true);
            app.cb40.Enable = 'off';
            app.cb40.Text = '40';
            app.cb50 = uicheckbox(app.Panel);
            app.cb50.ValueChangedFcn = createCallbackFcn(app, @cb50ValueChanged, true);
            app.cb50.Enable = 'off';
            app.cb50.Text = '50';
            app.cb60 = uicheckbox(app.Panel);
            app.cb60.ValueChangedFcn = createCallbackFcn(app, @cb60ValueChanged, true);
            app.cb60.Enable = 'off';
            app.cb60.Text = '60';
            app.cb70 = uicheckbox(app.Panel);
            app.cb70.ValueChangedFcn = createCallbackFcn(app, @cb70ValueChanged, true);
            app.cb70.Enable = 'off';
            app.cb70.Text = '70';
            app.cb80 = uicheckbox(app.Panel);
            app.cb80.ValueChangedFcn = createCallbackFcn(app, @cb80ValueChanged, true);
            app.cb80.Enable = 'off';
            app.cb80.Text = '80';
            app.cb90 = uicheckbox(app.Panel);
            app.cb90.ValueChangedFcn = createCallbackFcn(app, @cb90ValueChanged, true);
            app.cb90.Enable = 'off';
            app.cb90.Text = '90';
            app.cb100 = uicheckbox(app.Panel);
            app.cb100.ValueChangedFcn = createCallbackFcn(app, @cb100ValueChanged, true);
            app.cb100.Enable = 'off';
            app.cb100.Text = '100';
            app.nmSliderLabel = uilabel(app.EsferasTab);
            app.nmSliderLabel.Visible = 'off';
            app.nmSliderLabel.Text = '[nm]';
            app.nmSlider = uislider(app.EsferasTab);
            app.nmSlider.Limits = [10 100];
            app.nmSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.nmSlider.ValueChangedFcn = createCallbackFcn(app, @nmSliderValueChanged, true);
            app.nmSlider.MinorTicks = [];
            app.nmSlider.Visible = 'off';
            app.nmSlider.Value = 10;
            app.BarrasTab = uitab(app.TabGroup);
            app.BarrasTab.Title = 'Barras';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototipe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end